classdef Specific_Salary_Budget_Calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SalaryBudgetCalculatorUIFigure  matlab.ui.Figure
        EssentialsPanel                 matlab.ui.container.Panel
        MortgageRentUtilitiesLabel      matlab.ui.control.Label
        FoodEssentialsLabel             matlab.ui.control.Label
        rent                            matlab.ui.control.Label
        TotalLabel                      matlab.ui.control.Label
        total_essentials                matlab.ui.control.Label
        TravelFuelLabel_2               matlab.ui.control.Label
        TravelFuelEditField             matlab.ui.control.NumericEditField
        FoodToiletriesLabel             matlab.ui.control.Label
        FoodToiletriesEditField         matlab.ui.control.NumericEditField
        HealthLifeInsuranceLabel        matlab.ui.control.Label
        HealthLifeInsuranceEditField    matlab.ui.control.NumericEditField
        DebtminLabel                    matlab.ui.control.Label
        DebtminEditField                matlab.ui.control.NumericEditField
        LeisurePanel                    matlab.ui.container.Panel
        CarFinanceInsuranceEditFieldLabel  matlab.ui.control.Label
        CarFinanceInsuranceEditField    matlab.ui.control.NumericEditField
        ResturantShoppingEtcLabel       matlab.ui.control.Label
        ResturantShoppingEtcEditField   matlab.ui.control.NumericEditField
        VacationLabel                   matlab.ui.control.Label
        VacationEditField               matlab.ui.control.NumericEditField
        HobbiesOthersLabel              matlab.ui.control.Label
        HobbiesOthersEditField          matlab.ui.control.NumericEditField
        TotalLabel_2                    matlab.ui.control.Label
        total_leisure                   matlab.ui.control.Label
        InvestmentPanel                 matlab.ui.container.Panel
        DebtadditionalLabel_2           matlab.ui.control.Label
        DebtadditionalEditField         matlab.ui.control.NumericEditField
        SavingsISALabel_2               matlab.ui.control.Label
        SavingsISAEditField             matlab.ui.control.NumericEditField
        SharesCryptoGoldetcLabel        matlab.ui.control.Label
        SharesCryptoGoldetcEditField    matlab.ui.control.NumericEditField
        TotalLabel_3                    matlab.ui.control.Label
        total_investment                matlab.ui.control.Label
        UnspentfundsfromwantsshouldgotoinvestmentLabel_3  matlab.ui.control.Label
        Label_14                        matlab.ui.control.Label
        essentials                      matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        GrossAnnualSalaryDropDownLabel  matlab.ui.control.Label
        GrossAnnualSalaryDropDown       matlab.ui.control.DropDown
        KLabel                          matlab.ui.control.Label
        Label_36                        matlab.ui.control.Label
        SudentfinancetaxNIand3pensioncontributiondeductedLabel  matlab.ui.control.Label
        NetMonthlyIncomeLabel           matlab.ui.control.Label
        Label_17                        matlab.ui.control.Label
        CostofLivingGuide2018Panel      matlab.ui.container.Panel
        LocationLabel                   matlab.ui.control.Label
        RentLabel                       matlab.ui.control.Label
        BuyLabel                        matlab.ui.control.Label
        BedFlatStudioLabel              matlab.ui.control.Label
        BedHouseLabel                   matlab.ui.control.Label
        CornwallLabel                   matlab.ui.control.Label
        SwanseaLabel                    matlab.ui.control.Label
        CardiffLabel                    matlab.ui.control.Label
        LiverpoolLabel                  matlab.ui.control.Label
        PendleLabel                     matlab.ui.control.Label
        WatfordLabel                    matlab.ui.control.Label
        BangorLabel                     matlab.ui.control.Label
        LincolnLabel                    matlab.ui.control.Label
        CambridgeLabel                  matlab.ui.control.Label
        MiddlesboroughLabel             matlab.ui.control.Label
        pcmLabel_2                      matlab.ui.control.Label
        pcmLabel_6                      matlab.ui.control.Label
        pcmLabel_5                      matlab.ui.control.Label
        pcmLabel_8                      matlab.ui.control.Label
        pcmLabel_3                      matlab.ui.control.Label
        pcmLabel_9                      matlab.ui.control.Label
        pcmLabel_7                      matlab.ui.control.Label
        pcmLabel_4                      matlab.ui.control.Label
        pcmLabel_10                     matlab.ui.control.Label
        pcmLabel_11                     matlab.ui.control.Label
        Label_20                        matlab.ui.control.Label
        Label_21                        matlab.ui.control.Label
        BrightonLabel                   matlab.ui.control.Label
        pcmLabel_12                     matlab.ui.control.Label
        SouthamptonLabel                matlab.ui.control.Label
        pcmLabel_13                     matlab.ui.control.Label
        DerbyLabel                      matlab.ui.control.Label
        pcmLabel_14                     matlab.ui.control.Label
        LondonLabel                     matlab.ui.control.Label
        pcmLabel                        matlab.ui.control.Label
        KLabel_3                        matlab.ui.control.Label
        KLabel_7                        matlab.ui.control.Label
        KLabel_6                        matlab.ui.control.Label
        KLabel_9                        matlab.ui.control.Label
        KLabel_4                        matlab.ui.control.Label
        KLabel_10                       matlab.ui.control.Label
        KLabel_8                        matlab.ui.control.Label
        KLabel_5                        matlab.ui.control.Label
        KLabel_11                       matlab.ui.control.Label
        KLabel_12                       matlab.ui.control.Label
        KLabel_13                       matlab.ui.control.Label
        KLabel_14                       matlab.ui.control.Label
        KLabel_15                       matlab.ui.control.Label
        KLabel_2                        matlab.ui.control.Label
        billsexcludedLabel              matlab.ui.control.Label
        leisure                         matlab.ui.control.Label
        investment                      matlab.ui.control.Label
        Panel_4                         matlab.ui.container.Panel
        IncomeLabel                     matlab.ui.control.Label
        Panel_6                         matlab.ui.container.Panel
        RemainingIncomeLabel            matlab.ui.control.Label
        disposable_income               matlab.ui.control.Label
        UnspentfundsfromwantsshouldgotoinvestmentLabel_2  matlab.ui.control.Label
        UnspentfundsfromwantsshouldgotoinvestmentLabel_4  matlab.ui.control.Label
        Panel_7                         matlab.ui.container.Panel
        OutgoingLabel                   matlab.ui.control.Label
    end

    methods (Access = private)

        % Callback function
        function EntermonthlysalaryEditFieldValueChanged(app, event)


        end

        % Callback function
        function CalculateButtonPushed(app, event)


        end

        % Value changed function: GrossAnnualSalaryDropDown
        function GrossAnnualSalaryDropDownValueChanged(app, event)
           
           
            a = app.TravelFuelEditField.Value;
            b = app.FoodToiletriesEditField.Value;
            c = app.HealthLifeInsuranceEditField.Value;
            d = app.DebtminEditField.Value;
            
            e = app.CarFinanceInsuranceEditField.Value;
            f = app.ResturantShoppingEtcEditField.Value;
            g = app.VacationEditField.Value;
            h = app.HobbiesOthersEditField.Value;
            
            i = app.DebtadditionalEditField.Value;
            j = app.SavingsISAEditField.Value;
            k = app.SharesCryptoGoldetcEditField.Value;


            if(strcmp(app.GrossAnnualSalaryDropDown.Value,'0'))
            app.Label_17.Text = '0'; % Prints text to output console
            salary = 0
 
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'22'))
            app.Label_17.Text = '1,468'; % Prints text to output console
            salary = 1468
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'23'))
            app.Label_17.Text = '1,515'; % Prints text to output console
            salary = 1515
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'24'))
            app.Label_17.Text = '1,562'; % Prints text to output console
            salary = 1562
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'25'))
            app.Label_17.Text = '1,609'; % Prints text to output console
            salary = 1609
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'26'))
            app.Label_17.Text = '1,657'; % Prints text to output console
            salary = 1657
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'27'))
            app.Label_17.Text = '1,704'; % Prints text to output console
            salary = 1704
           
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'28'))
            app.Label_17.Text = '1,727'; % Prints text to output console
            salary = 1727
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'29'))
            app.Label_17.Text = '1,774'; % Prints text to output console
            salary = 1774
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'30'))
            app.Label_17.Text = '1,821'; % Prints text to output console
            salary = 1821
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'31'))
            app.Label_17.Text = '1,868'; % Prints text to output console
            salary = 1868
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'32'))
            app.Label_17.Text = '1,915'; % Prints text to output console
            salary = 1915
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'33'))
            app.Label_17.Text = '1,963'; % Prints text to output console
            salary = 1963
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'34'))
            app.Label_17.Text = '2,010'; % Prints text to output console
            salary = 2010
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'35'))
            app.Label_17.Text = '2,057'; % Prints text to output console
            salary = 2057
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'36'))
            app.Label_17.Text = '2,104'; % Prints text to output console
            salary = 2104
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'37'))
            app.Label_17.Text = '2,151'; % Prints text to output console
            salary = 2151
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'38'))
            app.Label_17.Text = '2,198'; % Prints text to output console
            salary = 2198
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'39'))
            app.Label_17.Text = '2,057'; % Prints text to output console
            salary = 2246
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'40'))
            app.Label_17.Text = '2,293'; % Prints text to output console
            salary = 2293
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'41'))
            app.Label_17.Text = '2,340'; % Prints text to output console
            salary = 2340
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'42'))
            app.Label_17.Text = '2,387'; % Prints text to output console
            salary = 2387
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'43'))
            app.Label_17.Text = '2,434'; % Prints text to output console
            salary = 2434
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'44'))
            app.Label_17.Text = '2,481'; % Prints text to output console
            salary = 2481
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'45'))
            app.Label_17.Text = '2,529'; % Prints text to output console
            salary = 2529
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'46'))
            app.Label_17.Text = '2,584'; % Prints text to output console
            salary = 2584
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'47'))
            app.Label_17.Text = '2,630'; % Prints text to output console
            salary = 2630
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'48'))
            app.Label_17.Text = '2,669'; % Prints text to output console
            salary = 2669


            else
            end
            
            
             % ESSENTIALS           
              essentials = round(0.5*salary);                    
              essentials1 = num2str(essentials);
              essentials11 = strcat(essentials1);
              app.essentials.Text = essentials11

              rent = round(0.3*salary)                    
              rent1 = num2str(rent);
              rent11 = strcat(rent1);
              app.rent.Text = rent11
      
              total_essentials =  essentials - rent - a - b - c - d;
              total_essentials1 = num2str(total_essentials);
              total_essentials11 = strcat(total_essentials1);
              app.total_essentials.Text = total_essentials11


              % LEISURE   
              leisure = round(0.3*salary);                    
              leisure1 = num2str(leisure);
              leisure11 = strcat(leisure1);
              app.leisure.Text = leisure11
              
              total_leisure =  leisure - e - f - g - h;
              total_leisure1 = num2str(total_leisure);
              total_leisure11 = strcat(total_leisure1);
              app.total_leisure.Text = total_leisure11


              % INVESTMENT   
              investment = round(0.2*salary);                    
              investment1 = num2str(investment);
              investment11 = strcat(investment1);
              app.investment.Text = investment11
              
              total_investment =  investment - i - j - k;
              total_investment1 = num2str(total_investment);
              total_investment11 = strcat(total_investment1);
              app.total_investment.Text = total_investment11

              disposable_income = total_essentials + total_leisure + total_investment
              disposable_income1 = num2str(disposable_income);
              disposable_income11 = strcat(disposable_income1);
              app.disposable_income.Text = disposable_income11


        end

        % Value changed function: TravelFuelEditField
        function TravelFuelEditFieldValueChanged(app, event)
a = app.TravelFuelEditField.Value;
            b = app.FoodToiletriesEditField.Value;
            c = app.HealthLifeInsuranceEditField.Value;
            d = app.DebtminEditField.Value;
            
            e = app.CarFinanceInsuranceEditField.Value;
            f = app.ResturantShoppingEtcEditField.Value;
            g = app.VacationEditField.Value;
            h = app.HobbiesOthersEditField.Value;
            
            i = app.DebtadditionalEditField.Value;
            j = app.SavingsISAEditField.Value;
            k = app.SharesCryptoGoldetcEditField.Value;


            if(strcmp(app.GrossAnnualSalaryDropDown.Value,'0'))
            app.Label_17.Text = '0'; % Prints text to output console
            salary = 0
 
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'22'))
            app.Label_17.Text = '1,468'; % Prints text to output console
            salary = 1468
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'23'))
            app.Label_17.Text = '1,515'; % Prints text to output console
            salary = 1515
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'24'))
            app.Label_17.Text = '1,562'; % Prints text to output console
            salary = 1562
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'25'))
            app.Label_17.Text = '1,609'; % Prints text to output console
            salary = 1609
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'26'))
            app.Label_17.Text = '1,657'; % Prints text to output console
            salary = 1657
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'27'))
            app.Label_17.Text = '1,704'; % Prints text to output console
            salary = 1704
           
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'28'))
            app.Label_17.Text = '1,727'; % Prints text to output console
            salary = 1727
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'29'))
            app.Label_17.Text = '1,774'; % Prints text to output console
            salary = 1774
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'30'))
            app.Label_17.Text = '1,821'; % Prints text to output console
            salary = 1821
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'31'))
            app.Label_17.Text = '1,868'; % Prints text to output console
            salary = 1868
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'32'))
            app.Label_17.Text = '1,915'; % Prints text to output console
            salary = 1915
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'33'))
            app.Label_17.Text = '1,963'; % Prints text to output console
            salary = 1963
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'34'))
            app.Label_17.Text = '2,010'; % Prints text to output console
            salary = 2010
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'35'))
            app.Label_17.Text = '2,057'; % Prints text to output console
            salary = 2057
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'36'))
            app.Label_17.Text = '2,104'; % Prints text to output console
            salary = 2104
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'37'))
            app.Label_17.Text = '2,151'; % Prints text to output console
            salary = 2151
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'38'))
            app.Label_17.Text = '2,198'; % Prints text to output console
            salary = 2198
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'39'))
            app.Label_17.Text = '2,057'; % Prints text to output console
            salary = 2246
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'40'))
            app.Label_17.Text = '2,293'; % Prints text to output console
            salary = 2293
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'41'))
            app.Label_17.Text = '2,340'; % Prints text to output console
            salary = 2340
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'42'))
            app.Label_17.Text = '2,387'; % Prints text to output console
            salary = 2387
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'43'))
            app.Label_17.Text = '2,434'; % Prints text to output console
            salary = 2434
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'44'))
            app.Label_17.Text = '2,481'; % Prints text to output console
            salary = 2481
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'45'))
            app.Label_17.Text = '2,529'; % Prints text to output console
            salary = 2529
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'46'))
            app.Label_17.Text = '2,584'; % Prints text to output console
            salary = 2584
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'47'))
            app.Label_17.Text = '2,630'; % Prints text to output console
            salary = 2630
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'48'))
            app.Label_17.Text = '2,669'; % Prints text to output console
            salary = 2669


            else
            end
            
            
             % ESSENTIALS           
              essentials = round(0.5*salary);                    
              essentials1 = num2str(essentials);
              essentials11 = strcat(essentials1);
              app.essentials.Text = essentials11

              rent = round(0.3*salary)                    
              rent1 = num2str(rent);
              rent11 = strcat(rent1);
              app.rent.Text = rent11
      
              total_essentials =  essentials - rent - a - b - c - d;
              total_essentials1 = num2str(total_essentials);
              total_essentials11 = strcat(total_essentials1);
              app.total_essentials.Text = total_essentials11


              % LEISURE   
              leisure = round(0.3*salary);                    
              leisure1 = num2str(leisure);
              leisure11 = strcat(leisure1);
              app.leisure.Text = leisure11
              
              total_leisure =  leisure - e - f - g - h;
              total_leisure1 = num2str(total_leisure);
              total_leisure11 = strcat(total_leisure1);
              app.total_leisure.Text = total_leisure11


              % INVESTMENT   
              investment = round(0.2*salary);                    
              investment1 = num2str(investment);
              investment11 = strcat(investment1);
              app.investment.Text = investment11
              
              total_investment =  investment - i - j - k;
              total_investment1 = num2str(total_investment);
              total_investment11 = strcat(total_investment1);
              app.total_investment.Text = total_investment11

              disposable_income = total_essentials + total_leisure + total_investment
              disposable_income1 = num2str(disposable_income);
              disposable_income11 = strcat(disposable_income1);
              app.disposable_income.Text = disposable_income11
                
        end

        % Value changed function: FoodToiletriesEditField
        function FoodToiletriesEditFieldValueChanged(app, event)
            
a = app.TravelFuelEditField.Value;
            b = app.FoodToiletriesEditField.Value;
            c = app.HealthLifeInsuranceEditField.Value;
            d = app.DebtminEditField.Value;
            
            e = app.CarFinanceInsuranceEditField.Value;
            f = app.ResturantShoppingEtcEditField.Value;
            g = app.VacationEditField.Value;
            h = app.HobbiesOthersEditField.Value;
            
            i = app.DebtadditionalEditField.Value;
            j = app.SavingsISAEditField.Value;
            k = app.SharesCryptoGoldetcEditField.Value;


            if(strcmp(app.GrossAnnualSalaryDropDown.Value,'0'))
            app.Label_17.Text = '0'; % Prints text to output console
            salary = 0
 
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'22'))
            app.Label_17.Text = '1,468'; % Prints text to output console
            salary = 1468
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'23'))
            app.Label_17.Text = '1,515'; % Prints text to output console
            salary = 1515
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'24'))
            app.Label_17.Text = '1,562'; % Prints text to output console
            salary = 1562
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'25'))
            app.Label_17.Text = '1,609'; % Prints text to output console
            salary = 1609
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'26'))
            app.Label_17.Text = '1,657'; % Prints text to output console
            salary = 1657
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'27'))
            app.Label_17.Text = '1,704'; % Prints text to output console
            salary = 1704
           
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'28'))
            app.Label_17.Text = '1,727'; % Prints text to output console
            salary = 1727
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'29'))
            app.Label_17.Text = '1,774'; % Prints text to output console
            salary = 1774
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'30'))
            app.Label_17.Text = '1,821'; % Prints text to output console
            salary = 1821
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'31'))
            app.Label_17.Text = '1,868'; % Prints text to output console
            salary = 1868
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'32'))
            app.Label_17.Text = '1,915'; % Prints text to output console
            salary = 1915
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'33'))
            app.Label_17.Text = '1,963'; % Prints text to output console
            salary = 1963
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'34'))
            app.Label_17.Text = '2,010'; % Prints text to output console
            salary = 2010
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'35'))
            app.Label_17.Text = '2,057'; % Prints text to output console
            salary = 2057
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'36'))
            app.Label_17.Text = '2,104'; % Prints text to output console
            salary = 2104
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'37'))
            app.Label_17.Text = '2,151'; % Prints text to output console
            salary = 2151
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'38'))
            app.Label_17.Text = '2,198'; % Prints text to output console
            salary = 2198
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'39'))
            app.Label_17.Text = '2,057'; % Prints text to output console
            salary = 2246
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'40'))
            app.Label_17.Text = '2,293'; % Prints text to output console
            salary = 2293
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'41'))
            app.Label_17.Text = '2,340'; % Prints text to output console
            salary = 2340
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'42'))
            app.Label_17.Text = '2,387'; % Prints text to output console
            salary = 2387
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'43'))
            app.Label_17.Text = '2,434'; % Prints text to output console
            salary = 2434
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'44'))
            app.Label_17.Text = '2,481'; % Prints text to output console
            salary = 2481
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'45'))
            app.Label_17.Text = '2,529'; % Prints text to output console
            salary = 2529
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'46'))
            app.Label_17.Text = '2,584'; % Prints text to output console
            salary = 2584
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'47'))
            app.Label_17.Text = '2,630'; % Prints text to output console
            salary = 2630
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'48'))
            app.Label_17.Text = '2,669'; % Prints text to output console
            salary = 2669


            else
            end
            
            
             % ESSENTIALS           
              essentials = round(0.5*salary);                    
              essentials1 = num2str(essentials);
              essentials11 = strcat(essentials1);
              app.essentials.Text = essentials11

              rent = round(0.3*salary)                    
              rent1 = num2str(rent);
              rent11 = strcat(rent1);
              app.rent.Text = rent11
      
              total_essentials =  essentials - rent - a - b - c - d;
              total_essentials1 = num2str(total_essentials);
              total_essentials11 = strcat(total_essentials1);
              app.total_essentials.Text = total_essentials11


              % LEISURE   
              leisure = round(0.3*salary);                    
              leisure1 = num2str(leisure);
              leisure11 = strcat(leisure1);
              app.leisure.Text = leisure11
              
              total_leisure =  leisure - e - f - g - h;
              total_leisure1 = num2str(total_leisure);
              total_leisure11 = strcat(total_leisure1);
              app.total_leisure.Text = total_leisure11


              % INVESTMENT   
              investment = round(0.2*salary);                    
              investment1 = num2str(investment);
              investment11 = strcat(investment1);
              app.investment.Text = investment11
              
              total_investment =  investment - i - j - k;
              total_investment1 = num2str(total_investment);
              total_investment11 = strcat(total_investment1);
              app.total_investment.Text = total_investment11

              disposable_income = total_essentials + total_leisure + total_investment
              disposable_income1 = num2str(disposable_income);
              disposable_income11 = strcat(disposable_income1);
              app.disposable_income.Text = disposable_income11
                       
        end

        % Value changed function: HealthLifeInsuranceEditField
        function HealthLifeInsuranceEditFieldValueChanged(app, event)
a = app.TravelFuelEditField.Value;
            b = app.FoodToiletriesEditField.Value;
            c = app.HealthLifeInsuranceEditField.Value;
            d = app.DebtminEditField.Value;
            
            e = app.CarFinanceInsuranceEditField.Value;
            f = app.ResturantShoppingEtcEditField.Value;
            g = app.VacationEditField.Value;
            h = app.HobbiesOthersEditField.Value;
            
            i = app.DebtadditionalEditField.Value;
            j = app.SavingsISAEditField.Value;
            k = app.SharesCryptoGoldetcEditField.Value;


            if(strcmp(app.GrossAnnualSalaryDropDown.Value,'0'))
            app.Label_17.Text = '0'; % Prints text to output console
            salary = 0
 
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'22'))
            app.Label_17.Text = '1,468'; % Prints text to output console
            salary = 1468
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'23'))
            app.Label_17.Text = '1,515'; % Prints text to output console
            salary = 1515
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'24'))
            app.Label_17.Text = '1,562'; % Prints text to output console
            salary = 1562
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'25'))
            app.Label_17.Text = '1,609'; % Prints text to output console
            salary = 1609
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'26'))
            app.Label_17.Text = '1,657'; % Prints text to output console
            salary = 1657
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'27'))
            app.Label_17.Text = '1,704'; % Prints text to output console
            salary = 1704
           
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'28'))
            app.Label_17.Text = '1,727'; % Prints text to output console
            salary = 1727
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'29'))
            app.Label_17.Text = '1,774'; % Prints text to output console
            salary = 1774
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'30'))
            app.Label_17.Text = '1,821'; % Prints text to output console
            salary = 1821
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'31'))
            app.Label_17.Text = '1,868'; % Prints text to output console
            salary = 1868
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'32'))
            app.Label_17.Text = '1,915'; % Prints text to output console
            salary = 1915
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'33'))
            app.Label_17.Text = '1,963'; % Prints text to output console
            salary = 1963
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'34'))
            app.Label_17.Text = '2,010'; % Prints text to output console
            salary = 2010
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'35'))
            app.Label_17.Text = '2,057'; % Prints text to output console
            salary = 2057
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'36'))
            app.Label_17.Text = '2,104'; % Prints text to output console
            salary = 2104
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'37'))
            app.Label_17.Text = '2,151'; % Prints text to output console
            salary = 2151
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'38'))
            app.Label_17.Text = '2,198'; % Prints text to output console
            salary = 2198
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'39'))
            app.Label_17.Text = '2,057'; % Prints text to output console
            salary = 2246
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'40'))
            app.Label_17.Text = '2,293'; % Prints text to output console
            salary = 2293
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'41'))
            app.Label_17.Text = '2,340'; % Prints text to output console
            salary = 2340
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'42'))
            app.Label_17.Text = '2,387'; % Prints text to output console
            salary = 2387
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'43'))
            app.Label_17.Text = '2,434'; % Prints text to output console
            salary = 2434
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'44'))
            app.Label_17.Text = '2,481'; % Prints text to output console
            salary = 2481
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'45'))
            app.Label_17.Text = '2,529'; % Prints text to output console
            salary = 2529
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'46'))
            app.Label_17.Text = '2,584'; % Prints text to output console
            salary = 2584
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'47'))
            app.Label_17.Text = '2,630'; % Prints text to output console
            salary = 2630
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'48'))
            app.Label_17.Text = '2,669'; % Prints text to output console
            salary = 2669


            else
            end
            
            
             % ESSENTIALS           
              essentials = round(0.5*salary);                    
              essentials1 = num2str(essentials);
              essentials11 = strcat(essentials1);
              app.essentials.Text = essentials11

              rent = round(0.3*salary)                    
              rent1 = num2str(rent);
              rent11 = strcat(rent1);
              app.rent.Text = rent11
      
              total_essentials =  essentials - rent - a - b - c - d;
              total_essentials1 = num2str(total_essentials);
              total_essentials11 = strcat(total_essentials1);
              app.total_essentials.Text = total_essentials11


              % LEISURE   
              leisure = round(0.3*salary);                    
              leisure1 = num2str(leisure);
              leisure11 = strcat(leisure1);
              app.leisure.Text = leisure11
              
              total_leisure =  leisure - e - f - g - h;
              total_leisure1 = num2str(total_leisure);
              total_leisure11 = strcat(total_leisure1);
              app.total_leisure.Text = total_leisure11


              % INVESTMENT   
              investment = round(0.2*salary);                    
              investment1 = num2str(investment);
              investment11 = strcat(investment1);
              app.investment.Text = investment11
              
              total_investment =  investment - i - j - k;
              total_investment1 = num2str(total_investment);
              total_investment11 = strcat(total_investment1);
              app.total_investment.Text = total_investment11

              disposable_income = total_essentials + total_leisure + total_investment
              disposable_income1 = num2str(disposable_income);
              disposable_income11 = strcat(disposable_income1);
              app.disposable_income.Text = disposable_income11
                          
        end

        % Value changed function: DebtminEditField
        function DebtminEditFieldValueChanged(app, event)
            a = app.TravelFuelEditField.Value;
            b = app.FoodToiletriesEditField.Value;
            c = app.HealthLifeInsuranceEditField.Value;
            d = app.DebtminEditField.Value;
            
            e = app.CarFinanceInsuranceEditField.Value;
            f = app.ResturantShoppingEtcEditField.Value;
            g = app.VacationEditField.Value;
            h = app.HobbiesOthersEditField.Value;
            
            i = app.DebtadditionalEditField.Value;
            j = app.SavingsISAEditField.Value;
            k = app.SharesCryptoGoldetcEditField.Value;


            if(strcmp(app.GrossAnnualSalaryDropDown.Value,'0'))
            app.Label_17.Text = '0'; % Prints text to output console
            salary = 0
 
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'22'))
            app.Label_17.Text = '1,468'; % Prints text to output console
            salary = 1468
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'23'))
            app.Label_17.Text = '1,515'; % Prints text to output console
            salary = 1515
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'24'))
            app.Label_17.Text = '1,562'; % Prints text to output console
            salary = 1562
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'25'))
            app.Label_17.Text = '1,609'; % Prints text to output console
            salary = 1609
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'26'))
            app.Label_17.Text = '1,657'; % Prints text to output console
            salary = 1657
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'27'))
            app.Label_17.Text = '1,704'; % Prints text to output console
            salary = 1704
           
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'28'))
            app.Label_17.Text = '1,727'; % Prints text to output console
            salary = 1727
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'29'))
            app.Label_17.Text = '1,774'; % Prints text to output console
            salary = 1774
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'30'))
            app.Label_17.Text = '1,821'; % Prints text to output console
            salary = 1821
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'31'))
            app.Label_17.Text = '1,868'; % Prints text to output console
            salary = 1868
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'32'))
            app.Label_17.Text = '1,915'; % Prints text to output console
            salary = 1915
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'33'))
            app.Label_17.Text = '1,963'; % Prints text to output console
            salary = 1963
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'34'))
            app.Label_17.Text = '2,010'; % Prints text to output console
            salary = 2010
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'35'))
            app.Label_17.Text = '2,057'; % Prints text to output console
            salary = 2057
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'36'))
            app.Label_17.Text = '2,104'; % Prints text to output console
            salary = 2104
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'37'))
            app.Label_17.Text = '2,151'; % Prints text to output console
            salary = 2151
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'38'))
            app.Label_17.Text = '2,198'; % Prints text to output console
            salary = 2198
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'39'))
            app.Label_17.Text = '2,057'; % Prints text to output console
            salary = 2246
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'40'))
            app.Label_17.Text = '2,293'; % Prints text to output console
            salary = 2293
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'41'))
            app.Label_17.Text = '2,340'; % Prints text to output console
            salary = 2340
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'42'))
            app.Label_17.Text = '2,387'; % Prints text to output console
            salary = 2387
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'43'))
            app.Label_17.Text = '2,434'; % Prints text to output console
            salary = 2434
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'44'))
            app.Label_17.Text = '2,481'; % Prints text to output console
            salary = 2481
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'45'))
            app.Label_17.Text = '2,529'; % Prints text to output console
            salary = 2529
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'46'))
            app.Label_17.Text = '2,584'; % Prints text to output console
            salary = 2584
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'47'))
            app.Label_17.Text = '2,630'; % Prints text to output console
            salary = 2630
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'48'))
            app.Label_17.Text = '2,669'; % Prints text to output console
            salary = 2669


            else
            end
            
            
             % ESSENTIALS           
              essentials = round(0.5*salary);                    
              essentials1 = num2str(essentials);
              essentials11 = strcat(essentials1);
              app.essentials.Text = essentials11

              rent = round(0.3*salary)                    
              rent1 = num2str(rent);
              rent11 = strcat(rent1);
              app.rent.Text = rent11
      
              total_essentials =  essentials - rent - a - b - c - d;
              total_essentials1 = num2str(total_essentials);
              total_essentials11 = strcat(total_essentials1);
              app.total_essentials.Text = total_essentials11


              % LEISURE   
              leisure = round(0.3*salary);                    
              leisure1 = num2str(leisure);
              leisure11 = strcat(leisure1);
              app.leisure.Text = leisure11
              
              total_leisure =  leisure - e - f - g - h;
              total_leisure1 = num2str(total_leisure);
              total_leisure11 = strcat(total_leisure1);
              app.total_leisure.Text = total_leisure11


              % INVESTMENT   
              investment = round(0.2*salary);                    
              investment1 = num2str(investment);
              investment11 = strcat(investment1);
              app.investment.Text = investment11
              
              total_investment =  investment - i - j - k;
              total_investment1 = num2str(total_investment);
              total_investment11 = strcat(total_investment1);
              app.total_investment.Text = total_investment11

              disposable_income = total_essentials + total_leisure + total_investment
              disposable_income1 = num2str(disposable_income);
              disposable_income11 = strcat(disposable_income1);
              app.disposable_income.Text = disposable_income11
              
            
        end

        % Value changed function: CarFinanceInsuranceEditField
        function CarFinanceInsuranceEditFieldValueChanged(app, event)
a = app.TravelFuelEditField.Value;
            b = app.FoodToiletriesEditField.Value;
            c = app.HealthLifeInsuranceEditField.Value;
            d = app.DebtminEditField.Value;
            
            e = app.CarFinanceInsuranceEditField.Value;
            f = app.ResturantShoppingEtcEditField.Value;
            g = app.VacationEditField.Value;
            h = app.HobbiesOthersEditField.Value;
            
            i = app.DebtadditionalEditField.Value;
            j = app.SavingsISAEditField.Value;
            k = app.SharesCryptoGoldetcEditField.Value;


            if(strcmp(app.GrossAnnualSalaryDropDown.Value,'0'))
            app.Label_17.Text = '0'; % Prints text to output console
            salary = 0
 
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'22'))
            app.Label_17.Text = '1,468'; % Prints text to output console
            salary = 1468
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'23'))
            app.Label_17.Text = '1,515'; % Prints text to output console
            salary = 1515
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'24'))
            app.Label_17.Text = '1,562'; % Prints text to output console
            salary = 1562
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'25'))
            app.Label_17.Text = '1,609'; % Prints text to output console
            salary = 1609
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'26'))
            app.Label_17.Text = '1,657'; % Prints text to output console
            salary = 1657
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'27'))
            app.Label_17.Text = '1,704'; % Prints text to output console
            salary = 1704
           
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'28'))
            app.Label_17.Text = '1,727'; % Prints text to output console
            salary = 1727
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'29'))
            app.Label_17.Text = '1,774'; % Prints text to output console
            salary = 1774
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'30'))
            app.Label_17.Text = '1,821'; % Prints text to output console
            salary = 1821
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'31'))
            app.Label_17.Text = '1,868'; % Prints text to output console
            salary = 1868
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'32'))
            app.Label_17.Text = '1,915'; % Prints text to output console
            salary = 1915
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'33'))
            app.Label_17.Text = '1,963'; % Prints text to output console
            salary = 1963
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'34'))
            app.Label_17.Text = '2,010'; % Prints text to output console
            salary = 2010
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'35'))
            app.Label_17.Text = '2,057'; % Prints text to output console
            salary = 2057
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'36'))
            app.Label_17.Text = '2,104'; % Prints text to output console
            salary = 2104
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'37'))
            app.Label_17.Text = '2,151'; % Prints text to output console
            salary = 2151
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'38'))
            app.Label_17.Text = '2,198'; % Prints text to output console
            salary = 2198
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'39'))
            app.Label_17.Text = '2,057'; % Prints text to output console
            salary = 2246
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'40'))
            app.Label_17.Text = '2,293'; % Prints text to output console
            salary = 2293
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'41'))
            app.Label_17.Text = '2,340'; % Prints text to output console
            salary = 2340
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'42'))
            app.Label_17.Text = '2,387'; % Prints text to output console
            salary = 2387
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'43'))
            app.Label_17.Text = '2,434'; % Prints text to output console
            salary = 2434
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'44'))
            app.Label_17.Text = '2,481'; % Prints text to output console
            salary = 2481
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'45'))
            app.Label_17.Text = '2,529'; % Prints text to output console
            salary = 2529
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'46'))
            app.Label_17.Text = '2,584'; % Prints text to output console
            salary = 2584
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'47'))
            app.Label_17.Text = '2,630'; % Prints text to output console
            salary = 2630
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'48'))
            app.Label_17.Text = '2,669'; % Prints text to output console
            salary = 2669


            else
            end
            
            
             % ESSENTIALS           
              essentials = round(0.5*salary);                    
              essentials1 = num2str(essentials);
              essentials11 = strcat(essentials1);
              app.essentials.Text = essentials11

              rent = round(0.3*salary)                    
              rent1 = num2str(rent);
              rent11 = strcat(rent1);
              app.rent.Text = rent11
      
              total_essentials =  essentials - rent - a - b - c - d;
              total_essentials1 = num2str(total_essentials);
              total_essentials11 = strcat(total_essentials1);
              app.total_essentials.Text = total_essentials11


              % LEISURE   
              leisure = round(0.3*salary);                    
              leisure1 = num2str(leisure);
              leisure11 = strcat(leisure1);
              app.leisure.Text = leisure11
              
              total_leisure =  leisure - e - f - g - h;
              total_leisure1 = num2str(total_leisure);
              total_leisure11 = strcat(total_leisure1);
              app.total_leisure.Text = total_leisure11


              % INVESTMENT   
              investment = round(0.2*salary);                    
              investment1 = num2str(investment);
              investment11 = strcat(investment1);
              app.investment.Text = investment11
              
              total_investment =  investment - i - j - k;
              total_investment1 = num2str(total_investment);
              total_investment11 = strcat(total_investment1);
              app.total_investment.Text = total_investment11

              disposable_income = total_essentials + total_leisure + total_investment
              disposable_income1 = num2str(disposable_income);
              disposable_income11 = strcat(disposable_income1);
              app.disposable_income.Text = disposable_income11
                          
        end

        % Value changed function: ResturantShoppingEtcEditField
        function ResturantShoppingEtcEditFieldValueChanged(app, event)
a = app.TravelFuelEditField.Value;
            b = app.FoodToiletriesEditField.Value;
            c = app.HealthLifeInsuranceEditField.Value;
            d = app.DebtminEditField.Value;
            
            e = app.CarFinanceInsuranceEditField.Value;
            f = app.ResturantShoppingEtcEditField.Value;
            g = app.VacationEditField.Value;
            h = app.HobbiesOthersEditField.Value;
            
            i = app.DebtadditionalEditField.Value;
            j = app.SavingsISAEditField.Value;
            k = app.SharesCryptoGoldetcEditField.Value;


            if(strcmp(app.GrossAnnualSalaryDropDown.Value,'0'))
            app.Label_17.Text = '0'; % Prints text to output console
            salary = 0
 
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'22'))
            app.Label_17.Text = '1,468'; % Prints text to output console
            salary = 1468
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'23'))
            app.Label_17.Text = '1,515'; % Prints text to output console
            salary = 1515
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'24'))
            app.Label_17.Text = '1,562'; % Prints text to output console
            salary = 1562
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'25'))
            app.Label_17.Text = '1,609'; % Prints text to output console
            salary = 1609
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'26'))
            app.Label_17.Text = '1,657'; % Prints text to output console
            salary = 1657
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'27'))
            app.Label_17.Text = '1,704'; % Prints text to output console
            salary = 1704
           
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'28'))
            app.Label_17.Text = '1,727'; % Prints text to output console
            salary = 1727
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'29'))
            app.Label_17.Text = '1,774'; % Prints text to output console
            salary = 1774
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'30'))
            app.Label_17.Text = '1,821'; % Prints text to output console
            salary = 1821
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'31'))
            app.Label_17.Text = '1,868'; % Prints text to output console
            salary = 1868
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'32'))
            app.Label_17.Text = '1,915'; % Prints text to output console
            salary = 1915
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'33'))
            app.Label_17.Text = '1,963'; % Prints text to output console
            salary = 1963
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'34'))
            app.Label_17.Text = '2,010'; % Prints text to output console
            salary = 2010
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'35'))
            app.Label_17.Text = '2,057'; % Prints text to output console
            salary = 2057
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'36'))
            app.Label_17.Text = '2,104'; % Prints text to output console
            salary = 2104
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'37'))
            app.Label_17.Text = '2,151'; % Prints text to output console
            salary = 2151
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'38'))
            app.Label_17.Text = '2,198'; % Prints text to output console
            salary = 2198
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'39'))
            app.Label_17.Text = '2,057'; % Prints text to output console
            salary = 2246
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'40'))
            app.Label_17.Text = '2,293'; % Prints text to output console
            salary = 2293
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'41'))
            app.Label_17.Text = '2,340'; % Prints text to output console
            salary = 2340
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'42'))
            app.Label_17.Text = '2,387'; % Prints text to output console
            salary = 2387
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'43'))
            app.Label_17.Text = '2,434'; % Prints text to output console
            salary = 2434
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'44'))
            app.Label_17.Text = '2,481'; % Prints text to output console
            salary = 2481
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'45'))
            app.Label_17.Text = '2,529'; % Prints text to output console
            salary = 2529
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'46'))
            app.Label_17.Text = '2,584'; % Prints text to output console
            salary = 2584
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'47'))
            app.Label_17.Text = '2,630'; % Prints text to output console
            salary = 2630
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'48'))
            app.Label_17.Text = '2,669'; % Prints text to output console
            salary = 2669


            else
            end
            
            
             % ESSENTIALS           
              essentials = round(0.5*salary);                    
              essentials1 = num2str(essentials);
              essentials11 = strcat(essentials1);
              app.essentials.Text = essentials11

              rent = round(0.3*salary)                    
              rent1 = num2str(rent);
              rent11 = strcat(rent1);
              app.rent.Text = rent11
      
              total_essentials =  essentials - rent - a - b - c - d;
              total_essentials1 = num2str(total_essentials);
              total_essentials11 = strcat(total_essentials1);
              app.total_essentials.Text = total_essentials11


              % LEISURE   
              leisure = round(0.3*salary);                    
              leisure1 = num2str(leisure);
              leisure11 = strcat(leisure1);
              app.leisure.Text = leisure11
              
              total_leisure =  leisure - e - f - g - h;
              total_leisure1 = num2str(total_leisure);
              total_leisure11 = strcat(total_leisure1);
              app.total_leisure.Text = total_leisure11


              % INVESTMENT   
              investment = round(0.2*salary);                    
              investment1 = num2str(investment);
              investment11 = strcat(investment1);
              app.investment.Text = investment11
              
              total_investment =  investment - i - j - k;
              total_investment1 = num2str(total_investment);
              total_investment11 = strcat(total_investment1);
              app.total_investment.Text = total_investment11

              disposable_income = total_essentials + total_leisure + total_investment
              disposable_income1 = num2str(disposable_income);
              disposable_income11 = strcat(disposable_income1);
              app.disposable_income.Text = disposable_income11
                          
        end

        % Value changed function: VacationEditField
        function VacationEditFieldValueChanged(app, event)
a = app.TravelFuelEditField.Value;
            b = app.FoodToiletriesEditField.Value;
            c = app.HealthLifeInsuranceEditField.Value;
            d = app.DebtminEditField.Value;
            
            e = app.CarFinanceInsuranceEditField.Value;
            f = app.ResturantShoppingEtcEditField.Value;
            g = app.VacationEditField.Value;
            h = app.HobbiesOthersEditField.Value;
            
            i = app.DebtadditionalEditField.Value;
            j = app.SavingsISAEditField.Value;
            k = app.SharesCryptoGoldetcEditField.Value;


            if(strcmp(app.GrossAnnualSalaryDropDown.Value,'0'))
            app.Label_17.Text = '0'; % Prints text to output console
            salary = 0
 
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'22'))
            app.Label_17.Text = '1,468'; % Prints text to output console
            salary = 1468
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'23'))
            app.Label_17.Text = '1,515'; % Prints text to output console
            salary = 1515
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'24'))
            app.Label_17.Text = '1,562'; % Prints text to output console
            salary = 1562
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'25'))
            app.Label_17.Text = '1,609'; % Prints text to output console
            salary = 1609
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'26'))
            app.Label_17.Text = '1,657'; % Prints text to output console
            salary = 1657
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'27'))
            app.Label_17.Text = '1,704'; % Prints text to output console
            salary = 1704
           
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'28'))
            app.Label_17.Text = '1,727'; % Prints text to output console
            salary = 1727
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'29'))
            app.Label_17.Text = '1,774'; % Prints text to output console
            salary = 1774
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'30'))
            app.Label_17.Text = '1,821'; % Prints text to output console
            salary = 1821
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'31'))
            app.Label_17.Text = '1,868'; % Prints text to output console
            salary = 1868
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'32'))
            app.Label_17.Text = '1,915'; % Prints text to output console
            salary = 1915
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'33'))
            app.Label_17.Text = '1,963'; % Prints text to output console
            salary = 1963
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'34'))
            app.Label_17.Text = '2,010'; % Prints text to output console
            salary = 2010
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'35'))
            app.Label_17.Text = '2,057'; % Prints text to output console
            salary = 2057
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'36'))
            app.Label_17.Text = '2,104'; % Prints text to output console
            salary = 2104
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'37'))
            app.Label_17.Text = '2,151'; % Prints text to output console
            salary = 2151
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'38'))
            app.Label_17.Text = '2,198'; % Prints text to output console
            salary = 2198
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'39'))
            app.Label_17.Text = '2,057'; % Prints text to output console
            salary = 2246
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'40'))
            app.Label_17.Text = '2,293'; % Prints text to output console
            salary = 2293
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'41'))
            app.Label_17.Text = '2,340'; % Prints text to output console
            salary = 2340
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'42'))
            app.Label_17.Text = '2,387'; % Prints text to output console
            salary = 2387
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'43'))
            app.Label_17.Text = '2,434'; % Prints text to output console
            salary = 2434
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'44'))
            app.Label_17.Text = '2,481'; % Prints text to output console
            salary = 2481
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'45'))
            app.Label_17.Text = '2,529'; % Prints text to output console
            salary = 2529
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'46'))
            app.Label_17.Text = '2,584'; % Prints text to output console
            salary = 2584
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'47'))
            app.Label_17.Text = '2,630'; % Prints text to output console
            salary = 2630
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'48'))
            app.Label_17.Text = '2,669'; % Prints text to output console
            salary = 2669


            else
            end
            
            
             % ESSENTIALS           
              essentials = round(0.5*salary);                    
              essentials1 = num2str(essentials);
              essentials11 = strcat(essentials1);
              app.essentials.Text = essentials11

              rent = round(0.3*salary)                    
              rent1 = num2str(rent);
              rent11 = strcat(rent1);
              app.rent.Text = rent11
      
              total_essentials =  essentials - rent - a - b - c - d;
              total_essentials1 = num2str(total_essentials);
              total_essentials11 = strcat(total_essentials1);
              app.total_essentials.Text = total_essentials11


              % LEISURE   
              leisure = round(0.3*salary);                    
              leisure1 = num2str(leisure);
              leisure11 = strcat(leisure1);
              app.leisure.Text = leisure11
              
              total_leisure =  leisure - e - f - g - h;
              total_leisure1 = num2str(total_leisure);
              total_leisure11 = strcat(total_leisure1);
              app.total_leisure.Text = total_leisure11


              % INVESTMENT   
              investment = round(0.2*salary);                    
              investment1 = num2str(investment);
              investment11 = strcat(investment1);
              app.investment.Text = investment11
              
              total_investment =  investment - i - j - k;
              total_investment1 = num2str(total_investment);
              total_investment11 = strcat(total_investment1);
              app.total_investment.Text = total_investment11

              disposable_income = total_essentials + total_leisure + total_investment
              disposable_income1 = num2str(disposable_income);
              disposable_income11 = strcat(disposable_income1);
              app.disposable_income.Text = disposable_income11
                          
        end

        % Value changed function: HobbiesOthersEditField
        function HobbiesOthersEditFieldValueChanged(app, event)
a = app.TravelFuelEditField.Value;
            b = app.FoodToiletriesEditField.Value;
            c = app.HealthLifeInsuranceEditField.Value;
            d = app.DebtminEditField.Value;
            
            e = app.CarFinanceInsuranceEditField.Value;
            f = app.ResturantShoppingEtcEditField.Value;
            g = app.VacationEditField.Value;
            h = app.HobbiesOthersEditField.Value;
            
            i = app.DebtadditionalEditField.Value;
            j = app.SavingsISAEditField.Value;
            k = app.SharesCryptoGoldetcEditField.Value;


            if(strcmp(app.GrossAnnualSalaryDropDown.Value,'0'))
            app.Label_17.Text = '0'; % Prints text to output console
            salary = 0
 
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'22'))
            app.Label_17.Text = '1,468'; % Prints text to output console
            salary = 1468
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'23'))
            app.Label_17.Text = '1,515'; % Prints text to output console
            salary = 1515
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'24'))
            app.Label_17.Text = '1,562'; % Prints text to output console
            salary = 1562
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'25'))
            app.Label_17.Text = '1,609'; % Prints text to output console
            salary = 1609
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'26'))
            app.Label_17.Text = '1,657'; % Prints text to output console
            salary = 1657
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'27'))
            app.Label_17.Text = '1,704'; % Prints text to output console
            salary = 1704
           
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'28'))
            app.Label_17.Text = '1,727'; % Prints text to output console
            salary = 1727
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'29'))
            app.Label_17.Text = '1,774'; % Prints text to output console
            salary = 1774
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'30'))
            app.Label_17.Text = '1,821'; % Prints text to output console
            salary = 1821
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'31'))
            app.Label_17.Text = '1,868'; % Prints text to output console
            salary = 1868
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'32'))
            app.Label_17.Text = '1,915'; % Prints text to output console
            salary = 1915
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'33'))
            app.Label_17.Text = '1,963'; % Prints text to output console
            salary = 1963
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'34'))
            app.Label_17.Text = '2,010'; % Prints text to output console
            salary = 2010
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'35'))
            app.Label_17.Text = '2,057'; % Prints text to output console
            salary = 2057
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'36'))
            app.Label_17.Text = '2,104'; % Prints text to output console
            salary = 2104
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'37'))
            app.Label_17.Text = '2,151'; % Prints text to output console
            salary = 2151
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'38'))
            app.Label_17.Text = '2,198'; % Prints text to output console
            salary = 2198
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'39'))
            app.Label_17.Text = '2,057'; % Prints text to output console
            salary = 2246
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'40'))
            app.Label_17.Text = '2,293'; % Prints text to output console
            salary = 2293
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'41'))
            app.Label_17.Text = '2,340'; % Prints text to output console
            salary = 2340
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'42'))
            app.Label_17.Text = '2,387'; % Prints text to output console
            salary = 2387
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'43'))
            app.Label_17.Text = '2,434'; % Prints text to output console
            salary = 2434
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'44'))
            app.Label_17.Text = '2,481'; % Prints text to output console
            salary = 2481
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'45'))
            app.Label_17.Text = '2,529'; % Prints text to output console
            salary = 2529
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'46'))
            app.Label_17.Text = '2,584'; % Prints text to output console
            salary = 2584
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'47'))
            app.Label_17.Text = '2,630'; % Prints text to output console
            salary = 2630
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'48'))
            app.Label_17.Text = '2,669'; % Prints text to output console
            salary = 2669


            else
            end
            
            
             % ESSENTIALS           
              essentials = round(0.5*salary);                    
              essentials1 = num2str(essentials);
              essentials11 = strcat(essentials1);
              app.essentials.Text = essentials11

              rent = round(0.3*salary)                    
              rent1 = num2str(rent);
              rent11 = strcat(rent1);
              app.rent.Text = rent11
      
              total_essentials =  essentials - rent - a - b - c - d;
              total_essentials1 = num2str(total_essentials);
              total_essentials11 = strcat(total_essentials1);
              app.total_essentials.Text = total_essentials11


              % LEISURE   
              leisure = round(0.3*salary);                    
              leisure1 = num2str(leisure);
              leisure11 = strcat(leisure1);
              app.leisure.Text = leisure11
              
              total_leisure =  leisure - e - f - g - h;
              total_leisure1 = num2str(total_leisure);
              total_leisure11 = strcat(total_leisure1);
              app.total_leisure.Text = total_leisure11


              % INVESTMENT   
              investment = round(0.2*salary);                    
              investment1 = num2str(investment);
              investment11 = strcat(investment1);
              app.investment.Text = investment11
              
              total_investment =  investment - i - j - k;
              total_investment1 = num2str(total_investment);
              total_investment11 = strcat(total_investment1);
              app.total_investment.Text = total_investment11

              disposable_income = total_essentials + total_leisure + total_investment
              disposable_income1 = num2str(disposable_income);
              disposable_income11 = strcat(disposable_income1);
              app.disposable_income.Text = disposable_income11
                          
        end

        % Value changed function: DebtadditionalEditField
        function DebtadditionalEditFieldValueChanged(app, event)
            a = app.TravelFuelEditField.Value;
            b = app.FoodToiletriesEditField.Value;
            c = app.HealthLifeInsuranceEditField.Value;
            d = app.DebtminEditField.Value;
            
            e = app.CarFinanceInsuranceEditField.Value;
            f = app.ResturantShoppingEtcEditField.Value;
            g = app.VacationEditField.Value;
            h = app.HobbiesOthersEditField.Value;
            
            i = app.DebtadditionalEditField.Value;
            j = app.SavingsISAEditField.Value;
            k = app.SharesCryptoGoldetcEditField.Value;


            if(strcmp(app.GrossAnnualSalaryDropDown.Value,'0'))
            app.Label_17.Text = '0'; % Prints text to output console
            salary = 0
 
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'22'))
            app.Label_17.Text = '1,468'; % Prints text to output console
            salary = 1468
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'23'))
            app.Label_17.Text = '1,515'; % Prints text to output console
            salary = 1515
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'24'))
            app.Label_17.Text = '1,562'; % Prints text to output console
            salary = 1562
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'25'))
            app.Label_17.Text = '1,609'; % Prints text to output console
            salary = 1609
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'26'))
            app.Label_17.Text = '1,657'; % Prints text to output console
            salary = 1657
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'27'))
            app.Label_17.Text = '1,704'; % Prints text to output console
            salary = 1704
           
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'28'))
            app.Label_17.Text = '1,727'; % Prints text to output console
            salary = 1727
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'29'))
            app.Label_17.Text = '1,774'; % Prints text to output console
            salary = 1774
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'30'))
            app.Label_17.Text = '1,821'; % Prints text to output console
            salary = 1821
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'31'))
            app.Label_17.Text = '1,868'; % Prints text to output console
            salary = 1868
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'32'))
            app.Label_17.Text = '1,915'; % Prints text to output console
            salary = 1915
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'33'))
            app.Label_17.Text = '1,963'; % Prints text to output console
            salary = 1963
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'34'))
            app.Label_17.Text = '2,010'; % Prints text to output console
            salary = 2010
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'35'))
            app.Label_17.Text = '2,057'; % Prints text to output console
            salary = 2057
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'36'))
            app.Label_17.Text = '2,104'; % Prints text to output console
            salary = 2104
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'37'))
            app.Label_17.Text = '2,151'; % Prints text to output console
            salary = 2151
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'38'))
            app.Label_17.Text = '2,198'; % Prints text to output console
            salary = 2198
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'39'))
            app.Label_17.Text = '2,057'; % Prints text to output console
            salary = 2246
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'40'))
            app.Label_17.Text = '2,293'; % Prints text to output console
            salary = 2293
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'41'))
            app.Label_17.Text = '2,340'; % Prints text to output console
            salary = 2340
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'42'))
            app.Label_17.Text = '2,387'; % Prints text to output console
            salary = 2387
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'43'))
            app.Label_17.Text = '2,434'; % Prints text to output console
            salary = 2434
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'44'))
            app.Label_17.Text = '2,481'; % Prints text to output console
            salary = 2481
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'45'))
            app.Label_17.Text = '2,529'; % Prints text to output console
            salary = 2529
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'46'))
            app.Label_17.Text = '2,584'; % Prints text to output console
            salary = 2584
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'47'))
            app.Label_17.Text = '2,630'; % Prints text to output console
            salary = 2630
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'48'))
            app.Label_17.Text = '2,669'; % Prints text to output console
            salary = 2669


            else
            end
            
            
             % ESSENTIALS           
              essentials = round(0.5*salary);                    
              essentials1 = num2str(essentials);
              essentials11 = strcat(essentials1);
              app.essentials.Text = essentials11

              rent = round(0.3*salary)                    
              rent1 = num2str(rent);
              rent11 = strcat(rent1);
              app.rent.Text = rent11
      
              total_essentials =  essentials - rent - a - b - c - d;
              total_essentials1 = num2str(total_essentials);
              total_essentials11 = strcat(total_essentials1);
              app.total_essentials.Text = total_essentials11


              % LEISURE   
              leisure = round(0.3*salary);                    
              leisure1 = num2str(leisure);
              leisure11 = strcat(leisure1);
              app.leisure.Text = leisure11
              
              total_leisure =  leisure - e - f - g - h;
              total_leisure1 = num2str(total_leisure);
              total_leisure11 = strcat(total_leisure1);
              app.total_leisure.Text = total_leisure11


              % INVESTMENT   
              investment = round(0.2*salary);                    
              investment1 = num2str(investment);
              investment11 = strcat(investment1);
              app.investment.Text = investment11
              
              total_investment =  investment - i - j - k;
              total_investment1 = num2str(total_investment);
              total_investment11 = strcat(total_investment1);
              app.total_investment.Text = total_investment11

              disposable_income = total_essentials + total_leisure + total_investment
              disposable_income1 = num2str(disposable_income);
              disposable_income11 = strcat(disposable_income1);
              app.disposable_income.Text = disposable_income11
              
            
        end

        % Value changed function: SavingsISAEditField
        function SavingsISAEditFieldValueChanged(app, event)
            a = app.TravelFuelEditField.Value;
            b = app.FoodToiletriesEditField.Value;
            c = app.HealthLifeInsuranceEditField.Value;
            d = app.DebtminEditField.Value;
            
            e = app.CarFinanceInsuranceEditField.Value;
            f = app.ResturantShoppingEtcEditField.Value;
            g = app.VacationEditField.Value;
            h = app.HobbiesOthersEditField.Value;
            
            i = app.DebtadditionalEditField.Value;
            j = app.SavingsISAEditField.Value;
            k = app.SharesCryptoGoldetcEditField.Value;


            if(strcmp(app.GrossAnnualSalaryDropDown.Value,'0'))
            app.Label_17.Text = '0'; % Prints text to output console
            salary = 0
 
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'22'))
            app.Label_17.Text = '1,468'; % Prints text to output console
            salary = 1468
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'23'))
            app.Label_17.Text = '1,515'; % Prints text to output console
            salary = 1515
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'24'))
            app.Label_17.Text = '1,562'; % Prints text to output console
            salary = 1562
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'25'))
            app.Label_17.Text = '1,609'; % Prints text to output console
            salary = 1609
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'26'))
            app.Label_17.Text = '1,657'; % Prints text to output console
            salary = 1657
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'27'))
            app.Label_17.Text = '1,704'; % Prints text to output console
            salary = 1704
           
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'28'))
            app.Label_17.Text = '1,727'; % Prints text to output console
            salary = 1727
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'29'))
            app.Label_17.Text = '1,774'; % Prints text to output console
            salary = 1774
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'30'))
            app.Label_17.Text = '1,821'; % Prints text to output console
            salary = 1821
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'31'))
            app.Label_17.Text = '1,868'; % Prints text to output console
            salary = 1868
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'32'))
            app.Label_17.Text = '1,915'; % Prints text to output console
            salary = 1915
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'33'))
            app.Label_17.Text = '1,963'; % Prints text to output console
            salary = 1963
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'34'))
            app.Label_17.Text = '2,010'; % Prints text to output console
            salary = 2010
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'35'))
            app.Label_17.Text = '2,057'; % Prints text to output console
            salary = 2057
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'36'))
            app.Label_17.Text = '2,104'; % Prints text to output console
            salary = 2104
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'37'))
            app.Label_17.Text = '2,151'; % Prints text to output console
            salary = 2151
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'38'))
            app.Label_17.Text = '2,198'; % Prints text to output console
            salary = 2198
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'39'))
            app.Label_17.Text = '2,057'; % Prints text to output console
            salary = 2246
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'40'))
            app.Label_17.Text = '2,293'; % Prints text to output console
            salary = 2293
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'41'))
            app.Label_17.Text = '2,340'; % Prints text to output console
            salary = 2340
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'42'))
            app.Label_17.Text = '2,387'; % Prints text to output console
            salary = 2387
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'43'))
            app.Label_17.Text = '2,434'; % Prints text to output console
            salary = 2434
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'44'))
            app.Label_17.Text = '2,481'; % Prints text to output console
            salary = 2481
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'45'))
            app.Label_17.Text = '2,529'; % Prints text to output console
            salary = 2529
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'46'))
            app.Label_17.Text = '2,584'; % Prints text to output console
            salary = 2584
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'47'))
            app.Label_17.Text = '2,630'; % Prints text to output console
            salary = 2630
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'48'))
            app.Label_17.Text = '2,669'; % Prints text to output console
            salary = 2669


            else
            end
            
            
             % ESSENTIALS           
              essentials = round(0.5*salary);                    
              essentials1 = num2str(essentials);
              essentials11 = strcat(essentials1);
              app.essentials.Text = essentials11

              rent = round(0.3*salary)                    
              rent1 = num2str(rent);
              rent11 = strcat(rent1);
              app.rent.Text = rent11
      
              total_essentials =  essentials - rent - a - b - c - d;
              total_essentials1 = num2str(total_essentials);
              total_essentials11 = strcat(total_essentials1);
              app.total_essentials.Text = total_essentials11


              % LEISURE   
              leisure = round(0.3*salary);                    
              leisure1 = num2str(leisure);
              leisure11 = strcat(leisure1);
              app.leisure.Text = leisure11
              
              total_leisure =  leisure - e - f - g - h;
              total_leisure1 = num2str(total_leisure);
              total_leisure11 = strcat(total_leisure1);
              app.total_leisure.Text = total_leisure11


              % INVESTMENT   
              investment = round(0.2*salary);                    
              investment1 = num2str(investment);
              investment11 = strcat(investment1);
              app.investment.Text = investment11
              
              total_investment =  investment - i - j - k;
              total_investment1 = num2str(total_investment);
              total_investment11 = strcat(total_investment1);
              app.total_investment.Text = total_investment11

              disposable_income = total_essentials + total_leisure + total_investment
              disposable_income1 = num2str(disposable_income);
              disposable_income11 = strcat(disposable_income1);
              app.disposable_income.Text = disposable_income11
              
            
        end

        % Value changed function: SharesCryptoGoldetcEditField
        function SharesCryptoGoldetcEditFieldValueChanged(app, event)
            a = app.TravelFuelEditField.Value;
            b = app.FoodToiletriesEditField.Value;
            c = app.HealthLifeInsuranceEditField.Value;
            d = app.DebtminEditField.Value;
            
            e = app.CarFinanceInsuranceEditField.Value;
            f = app.ResturantShoppingEtcEditField.Value;
            g = app.VacationEditField.Value;
            h = app.HobbiesOthersEditField.Value;
            
            i = app.DebtadditionalEditField.Value;
            j = app.SavingsISAEditField.Value;
            k = app.SharesCryptoGoldetcEditField.Value;


            if(strcmp(app.GrossAnnualSalaryDropDown.Value,'0'))
            app.Label_17.Text = '0'; % Prints text to output console
            salary = 0
 
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'22'))
            app.Label_17.Text = '1,468'; % Prints text to output console
            salary = 1468
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'23'))
            app.Label_17.Text = '1,515'; % Prints text to output console
            salary = 1515
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'24'))
            app.Label_17.Text = '1,562'; % Prints text to output console
            salary = 1562
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'25'))
            app.Label_17.Text = '1,609'; % Prints text to output console
            salary = 1609
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'26'))
            app.Label_17.Text = '1,657'; % Prints text to output console
            salary = 1657
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'27'))
            app.Label_17.Text = '1,704'; % Prints text to output console
            salary = 1704
           
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'28'))
            app.Label_17.Text = '1,727'; % Prints text to output console
            salary = 1727
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'29'))
            app.Label_17.Text = '1,774'; % Prints text to output console
            salary = 1774
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'30'))
            app.Label_17.Text = '1,821'; % Prints text to output console
            salary = 1821
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'31'))
            app.Label_17.Text = '1,868'; % Prints text to output console
            salary = 1868
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'32'))
            app.Label_17.Text = '1,915'; % Prints text to output console
            salary = 1915
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'33'))
            app.Label_17.Text = '1,963'; % Prints text to output console
            salary = 1963
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'34'))
            app.Label_17.Text = '2,010'; % Prints text to output console
            salary = 2010
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'35'))
            app.Label_17.Text = '2,057'; % Prints text to output console
            salary = 2057
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'36'))
            app.Label_17.Text = '2,104'; % Prints text to output console
            salary = 2104
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'37'))
            app.Label_17.Text = '2,151'; % Prints text to output console
            salary = 2151
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'38'))
            app.Label_17.Text = '2,198'; % Prints text to output console
            salary = 2198
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'39'))
            app.Label_17.Text = '2,057'; % Prints text to output console
            salary = 2246
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'40'))
            app.Label_17.Text = '2,293'; % Prints text to output console
            salary = 2293
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'41'))
            app.Label_17.Text = '2,340'; % Prints text to output console
            salary = 2340
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'42'))
            app.Label_17.Text = '2,387'; % Prints text to output console
            salary = 2387
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'43'))
            app.Label_17.Text = '2,434'; % Prints text to output console
            salary = 2434
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'44'))
            app.Label_17.Text = '2,481'; % Prints text to output console
            salary = 2481
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'45'))
            app.Label_17.Text = '2,529'; % Prints text to output console
            salary = 2529
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'46'))
            app.Label_17.Text = '2,584'; % Prints text to output console
            salary = 2584
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'47'))
            app.Label_17.Text = '2,630'; % Prints text to output console
            salary = 2630
            
            elseif(strcmp(app.GrossAnnualSalaryDropDown.Value,'48'))
            app.Label_17.Text = '2,669'; % Prints text to output console
            salary = 2669


            else
            end
            
            
             % ESSENTIALS           
              essentials = round(0.5*salary);                    
              essentials1 = num2str(essentials);
              essentials11 = strcat(essentials1);
              app.essentials.Text = essentials11

              rent = round(0.3*salary)                    
              rent1 = num2str(rent);
              rent11 = strcat(rent1);
              app.rent.Text = rent11
      
              total_essentials =  essentials - rent - a - b - c - d;
              total_essentials1 = num2str(total_essentials);
              total_essentials11 = strcat(total_essentials1);
              app.total_essentials.Text = total_essentials11


              % LEISURE   
              leisure = round(0.3*salary);                    
              leisure1 = num2str(leisure);
              leisure11 = strcat(leisure1);
              app.leisure.Text = leisure11
              
              total_leisure =  leisure - e - f - g - h;
              total_leisure1 = num2str(total_leisure);
              total_leisure11 = strcat(total_leisure1);
              app.total_leisure.Text = total_leisure11


              % INVESTMENT   
              investment = round(0.2*salary);                    
              investment1 = num2str(investment);
              investment11 = strcat(investment1);
              app.investment.Text = investment11
              
              total_investment =  investment - i - j - k;
              total_investment1 = num2str(total_investment);
              total_investment11 = strcat(total_investment1);
              app.total_investment.Text = total_investment11

              disposable_income = total_essentials + total_leisure + total_investment
              disposable_income1 = num2str(disposable_income);
              disposable_income11 = strcat(disposable_income1);
              app.disposable_income.Text = disposable_income11
              
            
        end

        % Callback function
        function CheckBoxtoEnterValueChanged(app, event)
            
        end

        % Callback function
        function net_monthly_inputValueChanged(app, event)
           
            
        end

        % Callback function
        function SwitchValueChanged(app, event)
            
        end

        % Callback function
        function SwitchValueChanged2(app, event)
            

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SalaryBudgetCalculatorUIFigure = uifigure;
            app.SalaryBudgetCalculatorUIFigure.Name = 'Salary Budget Calculator';
            app.EssentialsPanel = uipanel(app.SalaryBudgetCalculatorUIFigure);
            app.EssentialsPanel.Title = ' 50% Essentials                        £';
            app.MortgageRentUtilitiesLabel = uilabel(app.EssentialsPanel);
            app.MortgageRentUtilitiesLabel.Text = ' 30%  Mortgage/Rent/Utilities             - £';
            app.FoodEssentialsLabel = uilabel(app.EssentialsPanel);
            app.FoodEssentialsLabel.Text = ' 20%  Food+Essentials                          ';
            app.rent = uilabel(app.EssentialsPanel);
            app.rent.Text = '0';
            app.TotalLabel = uilabel(app.EssentialsPanel);
            app.TotalLabel.Text = 'Total                                        £';
            app.total_essentials = uilabel(app.EssentialsPanel);
            app.total_essentials.Text = '0';
            app.TravelFuelLabel_2 = uilabel(app.EssentialsPanel);
            app.TravelFuelLabel_2.Text = 'Travel/Fuel                                   - £';
            app.TravelFuelEditField = uieditfield(app.EssentialsPanel, 'numeric');
            app.TravelFuelEditField.Limits = [0 250];
            app.TravelFuelEditField.ValueDisplayFormat = '%.0f';
            app.TravelFuelEditField.ValueChangedFcn = createCallbackFcn(app, @TravelFuelEditFieldValueChanged, true);
            app.TravelFuelEditField.Value = 100;
            app.FoodToiletriesLabel = uilabel(app.EssentialsPanel);
            app.FoodToiletriesLabel.Text = 'Food/Toiletries                             - £';
            app.FoodToiletriesEditField = uieditfield(app.EssentialsPanel, 'numeric');
            app.FoodToiletriesEditField.Limits = [0 500];
            app.FoodToiletriesEditField.ValueDisplayFormat = '%.0f';
            app.FoodToiletriesEditField.ValueChangedFcn = createCallbackFcn(app, @FoodToiletriesEditFieldValueChanged, true);
            app.FoodToiletriesEditField.Value = 200;
            app.HealthLifeInsuranceLabel = uilabel(app.EssentialsPanel);
            app.HealthLifeInsuranceLabel.Text = 'Health/Life Insurance                   - £';
            app.HealthLifeInsuranceEditField = uieditfield(app.EssentialsPanel, 'numeric');
            app.HealthLifeInsuranceEditField.Limits = [0 200];
            app.HealthLifeInsuranceEditField.ValueDisplayFormat = '%.0f';
            app.HealthLifeInsuranceEditField.ValueChangedFcn = createCallbackFcn(app, @HealthLifeInsuranceEditFieldValueChanged, true);
            app.HealthLifeInsuranceEditField.Value = 70;
            app.DebtminLabel = uilabel(app.EssentialsPanel);
            app.DebtminLabel.Text = 'Debt (min)                                    - £';
            app.DebtminEditField = uieditfield(app.EssentialsPanel, 'numeric');
            app.DebtminEditField.Limits = [0 200];
            app.DebtminEditField.ValueDisplayFormat = '%.0f';
            app.DebtminEditField.ValueChangedFcn = createCallbackFcn(app, @DebtminEditFieldValueChanged, true);
            app.DebtminEditField.Value = 50;
            app.LeisurePanel = uipanel(app.SalaryBudgetCalculatorUIFigure);
            app.LeisurePanel.Title = '30% Leisure                              £';
            app.CarFinanceInsuranceEditFieldLabel = uilabel(app.LeisurePanel);
            app.CarFinanceInsuranceEditFieldLabel.Text = 'Car/Finance/Insurance                - £';
            app.CarFinanceInsuranceEditField = uieditfield(app.LeisurePanel, 'numeric');
            app.CarFinanceInsuranceEditField.Limits = [0 700];
            app.CarFinanceInsuranceEditField.ValueDisplayFormat = '%.0f';
            app.CarFinanceInsuranceEditField.ValueChangedFcn = createCallbackFcn(app, @CarFinanceInsuranceEditFieldValueChanged, true);
            app.CarFinanceInsuranceEditField.Value = 370;
            app.ResturantShoppingEtcLabel = uilabel(app.LeisurePanel);
            app.ResturantShoppingEtcLabel.Text = '     Resturant/Shopping/Etc               - £';
            app.ResturantShoppingEtcEditField = uieditfield(app.LeisurePanel, 'numeric');
            app.ResturantShoppingEtcEditField.Limits = [0 700];
            app.ResturantShoppingEtcEditField.ValueDisplayFormat = '%.0f';
            app.ResturantShoppingEtcEditField.ValueChangedFcn = createCallbackFcn(app, @ResturantShoppingEtcEditFieldValueChanged, true);
            app.ResturantShoppingEtcEditField.Value = 150;
            app.VacationLabel = uilabel(app.LeisurePanel);
            app.VacationLabel.Text = '     Vacation                                       - £';
            app.VacationEditField = uieditfield(app.LeisurePanel, 'numeric');
            app.VacationEditField.Limits = [0 700];
            app.VacationEditField.ValueDisplayFormat = '%.0f';
            app.VacationEditField.ValueChangedFcn = createCallbackFcn(app, @VacationEditFieldValueChanged, true);
            app.VacationEditField.Value = 20;
            app.HobbiesOthersLabel = uilabel(app.LeisurePanel);
            app.HobbiesOthersLabel.Text = '     Hobbies/Others                            - £';
            app.HobbiesOthersEditField = uieditfield(app.LeisurePanel, 'numeric');
            app.HobbiesOthersEditField.Limits = [0 700];
            app.HobbiesOthersEditField.ValueDisplayFormat = '%.0f';
            app.HobbiesOthersEditField.ValueChangedFcn = createCallbackFcn(app, @HobbiesOthersEditFieldValueChanged, true);
            app.HobbiesOthersEditField.Value = 40;
            app.TotalLabel_2 = uilabel(app.LeisurePanel);
            app.TotalLabel_2.Text = 'Total                                        £';
            app.total_leisure = uilabel(app.LeisurePanel);
            app.total_leisure.Text = '0';
            app.InvestmentPanel = uipanel(app.SalaryBudgetCalculatorUIFigure);
            app.InvestmentPanel.Title = '20% Investment                        £';
            app.DebtadditionalLabel_2 = uilabel(app.InvestmentPanel);
            app.DebtadditionalLabel_2.Text = '     Debt(additional)                            - £';
            app.DebtadditionalEditField = uieditfield(app.InvestmentPanel, 'numeric');
            app.DebtadditionalEditField.Limits = [0 1000];
            app.DebtadditionalEditField.ValueDisplayFormat = '%.0f';
            app.DebtadditionalEditField.ValueChangedFcn = createCallbackFcn(app, @DebtadditionalEditFieldValueChanged, true);
            app.DebtadditionalEditField.Value = 300;
            app.SavingsISALabel_2 = uilabel(app.InvestmentPanel);
            app.SavingsISALabel_2.Text = '     Savings/ISA                                  - £';
            app.SavingsISAEditField = uieditfield(app.InvestmentPanel, 'numeric');
            app.SavingsISAEditField.Limits = [0 1000];
            app.SavingsISAEditField.ValueDisplayFormat = '%.0f';
            app.SavingsISAEditField.ValueChangedFcn = createCallbackFcn(app, @SavingsISAEditFieldValueChanged, true);
            app.SavingsISAEditField.Value = 50;
            app.SharesCryptoGoldetcLabel = uilabel(app.InvestmentPanel);
            app.SharesCryptoGoldetcLabel.Text = '     Shares/Crypto/Gold etc                - £';
            app.SharesCryptoGoldetcEditField = uieditfield(app.InvestmentPanel, 'numeric');
            app.SharesCryptoGoldetcEditField.Limits = [0 1000];
            app.SharesCryptoGoldetcEditField.ValueDisplayFormat = '%.0f';
            app.SharesCryptoGoldetcEditField.ValueChangedFcn = createCallbackFcn(app, @SharesCryptoGoldetcEditFieldValueChanged, true);
            app.SharesCryptoGoldetcEditField.Value = 50;
            app.TotalLabel_3 = uilabel(app.InvestmentPanel);
            app.TotalLabel_3.Text = 'Total                                        £';
            app.total_investment = uilabel(app.InvestmentPanel);
            app.total_investment.Text = '0';
            app.UnspentfundsfromwantsshouldgotoinvestmentLabel_3 = uilabel(app.SalaryBudgetCalculatorUIFigure);
            app.UnspentfundsfromwantsshouldgotoinvestmentLabel_3.Text = 'Andre Munoz 2018';
            app.Label_14 = uilabel(app.SalaryBudgetCalculatorUIFigure);
            app.Label_14.Text = '';
            app.essentials = uilabel(app.SalaryBudgetCalculatorUIFigure);
            app.essentials.Text = '0';
            app.Panel = uipanel(app.SalaryBudgetCalculatorUIFigure);
            app.GrossAnnualSalaryDropDownLabel = uilabel(app.Panel);
            app.GrossAnnualSalaryDropDownLabel.Text = 'Gross Annual Salary £';
            app.GrossAnnualSalaryDropDown = uidropdown(app.Panel);
            app.GrossAnnualSalaryDropDown.Items = {'0', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48'};
            app.GrossAnnualSalaryDropDown.ValueChangedFcn = createCallbackFcn(app, @GrossAnnualSalaryDropDownValueChanged, true);
            app.GrossAnnualSalaryDropDown.Value = '0';
            app.KLabel = uilabel(app.Panel);
            app.KLabel.Text = 'K';
            app.Label_36 = uilabel(app.Panel);
            app.Label_36.Text = '';
            app.SudentfinancetaxNIand3pensioncontributiondeductedLabel = uilabel(app.Panel);
            app.SudentfinancetaxNIand3pensioncontributiondeductedLabel.Text = 'Sudent finance, tax, NI and 3% pension contribution deducted';
            app.NetMonthlyIncomeLabel = uilabel(app.Panel);
            app.NetMonthlyIncomeLabel.Text = 'Net Monthly Income £';
            app.Label_17 = uilabel(app.Panel);
            app.Label_17.Text = '0';
            app.CostofLivingGuide2018Panel = uipanel(app.SalaryBudgetCalculatorUIFigure);
            app.CostofLivingGuide2018Panel.Title = 'Cost of Living Guide 2018';
            app.LocationLabel = uilabel(app.CostofLivingGuide2018Panel);
            app.LocationLabel.Text = 'Location';
            app.RentLabel = uilabel(app.CostofLivingGuide2018Panel);
            app.RentLabel.Text = 'Rent';
            app.BuyLabel = uilabel(app.CostofLivingGuide2018Panel);
            app.BuyLabel.Text = 'Buy';
            app.BedFlatStudioLabel = uilabel(app.CostofLivingGuide2018Panel);
            app.BedFlatStudioLabel.Text = '(1Bed Flat / Studio)';
            app.BedHouseLabel = uilabel(app.CostofLivingGuide2018Panel);
            app.BedHouseLabel.Text = '(1/2 Bed House)';
            app.CornwallLabel = uilabel(app.CostofLivingGuide2018Panel);
            app.CornwallLabel.Text = 'Cornwall';
            app.SwanseaLabel = uilabel(app.CostofLivingGuide2018Panel);
            app.SwanseaLabel.Text = 'Swansea';
            app.CardiffLabel = uilabel(app.CostofLivingGuide2018Panel);
            app.CardiffLabel.Text = 'Cardiff';
            app.LiverpoolLabel = uilabel(app.CostofLivingGuide2018Panel);
            app.LiverpoolLabel.Text = 'Liverpool';
            app.PendleLabel = uilabel(app.CostofLivingGuide2018Panel);
            app.PendleLabel.Text = 'Pendle';
            app.WatfordLabel = uilabel(app.CostofLivingGuide2018Panel);
            app.WatfordLabel.Text = 'Watford';
            app.BangorLabel = uilabel(app.CostofLivingGuide2018Panel);
            app.BangorLabel.Text = 'Bangor';
            app.LincolnLabel = uilabel(app.CostofLivingGuide2018Panel);
            app.LincolnLabel.Text = 'Lincoln';
            app.CambridgeLabel = uilabel(app.CostofLivingGuide2018Panel);
            app.CambridgeLabel.Text = 'Cambridge';
            app.MiddlesboroughLabel = uilabel(app.CostofLivingGuide2018Panel);
            app.MiddlesboroughLabel.Text = 'Middlesborough';
            app.pcmLabel_2 = uilabel(app.CostofLivingGuide2018Panel);
            app.pcmLabel_2.Text = '300 pcm';
            app.pcmLabel_6 = uilabel(app.CostofLivingGuide2018Panel);
            app.pcmLabel_6.Text = '500 pcm';
            app.pcmLabel_5 = uilabel(app.CostofLivingGuide2018Panel);
            app.pcmLabel_5.Text = '450 pcm';
            app.pcmLabel_8 = uilabel(app.CostofLivingGuide2018Panel);
            app.pcmLabel_8.Text = '375 pcm';
            app.pcmLabel_3 = uilabel(app.CostofLivingGuide2018Panel);
            app.pcmLabel_3.Text = '350 pcm';
            app.pcmLabel_9 = uilabel(app.CostofLivingGuide2018Panel);
            app.pcmLabel_9.Text = '650 pcm';
            app.pcmLabel_7 = uilabel(app.CostofLivingGuide2018Panel);
            app.pcmLabel_7.Text = '490 pcm';
            app.pcmLabel_4 = uilabel(app.CostofLivingGuide2018Panel);
            app.pcmLabel_4.Text = '470 pcm';
            app.pcmLabel_10 = uilabel(app.CostofLivingGuide2018Panel);
            app.pcmLabel_10.Text = '625 pcm';
            app.pcmLabel_11 = uilabel(app.CostofLivingGuide2018Panel);
            app.pcmLabel_11.Text = '320 pcm';
            app.Label_20 = uilabel(app.CostofLivingGuide2018Panel);
            app.Label_20.Text = '£';
            app.Label_21 = uilabel(app.CostofLivingGuide2018Panel);
            app.Label_21.Text = '£';
            app.BrightonLabel = uilabel(app.CostofLivingGuide2018Panel);
            app.BrightonLabel.Text = 'Brighton';
            app.pcmLabel_12 = uilabel(app.CostofLivingGuide2018Panel);
            app.pcmLabel_12.Text = '500 pcm';
            app.SouthamptonLabel = uilabel(app.CostofLivingGuide2018Panel);
            app.SouthamptonLabel.Text = 'Southampton';
            app.pcmLabel_13 = uilabel(app.CostofLivingGuide2018Panel);
            app.pcmLabel_13.Text = '450 pcm';
            app.DerbyLabel = uilabel(app.CostofLivingGuide2018Panel);
            app.DerbyLabel.Text = 'Derby';
            app.pcmLabel_14 = uilabel(app.CostofLivingGuide2018Panel);
            app.pcmLabel_14.Text = '475 pcm';
            app.LondonLabel = uilabel(app.CostofLivingGuide2018Panel);
            app.LondonLabel.Text = 'London';
            app.pcmLabel = uilabel(app.CostofLivingGuide2018Panel);
            app.pcmLabel.Text = '750 pcm';
            app.KLabel_3 = uilabel(app.CostofLivingGuide2018Panel);
            app.KLabel_3.Text = '100 K';
            app.KLabel_7 = uilabel(app.CostofLivingGuide2018Panel);
            app.KLabel_7.Text = '125 K';
            app.KLabel_6 = uilabel(app.CostofLivingGuide2018Panel);
            app.KLabel_6.Text = '130 K';
            app.KLabel_9 = uilabel(app.CostofLivingGuide2018Panel);
            app.KLabel_9.Text = '90 K';
            app.KLabel_4 = uilabel(app.CostofLivingGuide2018Panel);
            app.KLabel_4.Text = '90 K';
            app.KLabel_10 = uilabel(app.CostofLivingGuide2018Panel);
            app.KLabel_10.Text = '270 K';
            app.KLabel_8 = uilabel(app.CostofLivingGuide2018Panel);
            app.KLabel_8.Text = '140 K';
            app.KLabel_5 = uilabel(app.CostofLivingGuide2018Panel);
            app.KLabel_5.Text = '150 K';
            app.KLabel_11 = uilabel(app.CostofLivingGuide2018Panel);
            app.KLabel_11.Text = '325 K';
            app.KLabel_12 = uilabel(app.CostofLivingGuide2018Panel);
            app.KLabel_12.Text = '50 K';
            app.KLabel_13 = uilabel(app.CostofLivingGuide2018Panel);
            app.KLabel_13.Text = '290 K';
            app.KLabel_14 = uilabel(app.CostofLivingGuide2018Panel);
            app.KLabel_14.Text = '190 K';
            app.KLabel_15 = uilabel(app.CostofLivingGuide2018Panel);
            app.KLabel_15.Text = '75 K';
            app.KLabel_2 = uilabel(app.CostofLivingGuide2018Panel);
            app.KLabel_2.Text = '300 K';
            app.billsexcludedLabel = uilabel(app.CostofLivingGuide2018Panel);
            app.billsexcludedLabel.Text = 'bills excluded';
            app.leisure = uilabel(app.SalaryBudgetCalculatorUIFigure);
            app.leisure.Text = '0';
            app.investment = uilabel(app.SalaryBudgetCalculatorUIFigure);
            app.investment.Text = '0';
            app.Panel_4 = uipanel(app.SalaryBudgetCalculatorUIFigure);
            app.IncomeLabel = uilabel(app.Panel_4);
            app.IncomeLabel.Text = 'Income';
            app.Panel_6 = uipanel(app.SalaryBudgetCalculatorUIFigure);
            app.RemainingIncomeLabel = uilabel(app.Panel_6);
            app.RemainingIncomeLabel.Text = 'Remaining Income £';
            app.disposable_income = uilabel(app.Panel_6);
            app.disposable_income.Text = '0';
            app.UnspentfundsfromwantsshouldgotoinvestmentLabel_2 = uilabel(app.Panel_6);
            app.UnspentfundsfromwantsshouldgotoinvestmentLabel_2.Text = 'Ideally this would go back into investment. If the value';
            app.UnspentfundsfromwantsshouldgotoinvestmentLabel_4 = uilabel(app.Panel_6);
            app.UnspentfundsfromwantsshouldgotoinvestmentLabel_4.Text = ' is negative then cut backs or increased earnings are require.';
            app.Panel_7 = uipanel(app.SalaryBudgetCalculatorUIFigure);
            app.OutgoingLabel = uilabel(app.Panel_7);
            app.OutgoingLabel.Text = 'Outgoing';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Specific_Salary_Budget_Calculator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SalaryBudgetCalculatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SalaryBudgetCalculatorUIFigure)
        end
    end
end