classdef I_Encryption < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IEncryption                matlab.ui.Figure
        Panel                      matlab.ui.container.Panel
        UIAxes                     matlab.ui.control.UIAxes
        Label_4                    matlab.ui.control.Label
        Label_6                    matlab.ui.control.Label
        Label_7                    matlab.ui.control.Label
        Label_8                    matlab.ui.control.Label
        WelcometoIEncryptionLabel  matlab.ui.control.Label
        Label_10                   matlab.ui.control.Label
        InputPanel                 matlab.ui.container.Panel
        DropDown                   matlab.ui.control.DropDown
        BrowseButton               matlab.ui.control.Button
        selectoptionLabel          matlab.ui.control.Label
        browsefilesLabel           matlab.ui.control.Label
        AndreMunoz2018Label        matlab.ui.control.Label
    end

    methods (Access = private)

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;

            if(strcmp(app.DropDown.Value,'Encrypt'))
            app.WelcometoIEncryptionLabel.Text = 'Select image to encrypt'; % Prints text to output console
            elseif(strcmp(app.DropDown.Value,'Decrypt'))
            app.WelcometoIEncryptionLabel.Text = 'Select "Encrypted_Image...png"'; % Prints text to output console

            else
            end


        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            
           
          if(strcmp(app.DropDown.Value,'Encrypt'))
              
              
            nR = 9;lR = 5;out = randperm(nR,lR); % generates random numbers
            rN = num2str(out); 
            rN = rN(find(~isspace(rN)));
                
             app.WelcometoIEncryptionLabel.Text = 'Select image to encrypt'; % Prints text to output console

            [imagename,imagepath] = uigetfile({'*.jpg';'*.png';'*.bmp';'*.tif'},'Select image to encrypt');
            obj = imread(strcat(imagepath,imagename)); 
            string1 = strcat(imagepath,'Encrypted_Image_',rN,'.png');
            string2 = strcat(imagepath,'Key_',rN,'.txt');
            string3 = strcat('Files saved in ',imagepath);
            app.WelcometoIEncryptionLabel.Text = '...processing...'; % Prints text to output console


            % Reads in image for preparation and generating encrytion key.
            I = obj;
            % Converts RGB to grayscale.
                 if size(I,3)==3
                    II = rgb2gray(I);
                 else
                    II = I;
                end
            H = size(II);
            [l,w] = size(II);
            % Passing the II to tasks later was problematic, so it was saved 
            % and imported later. The joys of MATLAB :)
            %imwrite(II,'Image.tif');
            imshow(II,'Parent',app.UIAxes)
            pause(1)
            
            % Creates a key as a random binary array the same size as the image.
            AI = zeros([l,w]);
            E = l*w;
            % Makes the array have 0.5 1s and 0s.
            Ni = round(0.5*E);
            AI(randperm(numel(AI),Ni)) = 1;
            % Passing the A to tasks later was problematic, so it was saved 
            % and imported later. The joys of MATLAB :)
            %csvwrite('Key.txt',AI)
            imshow(AI,'Parent',app.UIAxes)
            pause(1)
            
            % First the encrytion key is prepared for encoding. 
            %A1 = importdata('Key.txt'); %Converts grayscale antimask to binary.
            A1 = AI; %Converts grayscale antimask to binary.

            A = double(A1); % Converts from logical to double.
            Asize = size(A); 
            [Ah,Aw] = size(A1);
            Ae = (Asize*2)-1; % 2x2 mosaic template minus one row and column.
            f = floor((Ae-Asize)/2); % Working out amount to pad array in mosaic template.
            A_MOS = padarray(A,f,'circular'); % Padds array and creates a mosaicked mask.
            open = sum(A(:) == 1); % Counts number of 1s in the array.
            OF = (open/(Ah*Aw)); % Finds open fraction.
            I = 1; % Sets open element at 1.
            O = OF/(OF-1); % Sets calculated value for closed elements
            G = 2*A_MOS-1; % sets binary 0 to -1 %Sets.
            G( G==1 )= I; % Changes open elements.
            G( G==-1 )= O; % Changes closed elements.

            % The images is imported. II
            %O = imread('Image.tif');
            O = II;
            O1 = imresize(O,[Ah Aw]); % Resize image.
            [Oh,Ow] = size(O1); 


            D = normxcorr2(O1,A_MOS); % Correlates the image with key to encrypt.
            [Dh,Dw] = size(D); % Dimension of correlated array.
            dh = floor(Dh-Oh)/2; 
            dw = floor(Dw-Ow)/2; 
            COR = imcrop(D,[dw dh Ow-1 Oh-1]); % Crop image     
            COR1 = mat2gray(COR); % Converts %binary to grayscale to view.
            imshow(COR1,'Parent',app.UIAxes)
            imwrite(COR1,string1);
            csvwrite(string2,G);
            pause(1)
            app.WelcometoIEncryptionLabel.Text = string3; % Prints text to output console
            pause(1)
            app.WelcometoIEncryptionLabel.Text = 'Complete'; % Prints text to output console


          elseif(strcmp(app.DropDown.Value,'Decrypt'))


            [imagename,imagepath] = uigetfile({'*.png'},'Select encrypted image');
            string4 = strcat(imagepath,'Decrypted_Image.png');
            string5 = strcat('Files saved in ',imagepath,'as "Decrypted_Image.png".');
            COR = imread(strcat(imagepath,imagename)); 
            [Oh,Ow] = size(COR);

            COR1 = mat2gray(COR); % Converts %binary to grayscale to view.
            imshow(COR1,'Parent',app.UIAxes);
            pause(1)
            app.WelcometoIEncryptionLabel.Text = 'Select Key...txt'; % Prints text to output console
            [keyname,keypath] = uigetfile({'*.txt'},'Select Key');
            G = importdata(strcat(keypath,keyname)); 
            R = normxcorr2(COR,G); % Correlates the ecrypted image with key to decrypt.
            [Rh,Rw] = size(R); 
            Rh = floor(Rh-Oh)/2; 
            Rw = floor(Rw-Ow)/2; 
            rCOR = imcrop(R,[Rw Rh Ow-1 Oh-1]); % Crop image     
            rCOR1 = mat2gray(rCOR); % Converts binary to grayscale to view.
            %delete Key.txt
            %delete Image.tif
            imwrite(rCOR1,string4);
            imshow(rCOR1,'Parent',app.UIAxes)
            pause(1)
            app.WelcometoIEncryptionLabel.Text = string5; % Prints text to output console
            pause(1)
            app.WelcometoIEncryptionLabel.Text = 'Complete'; % Prints text to output console


          else
          end
            
          
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IEncryption = uifigure;
            app.IEncryption.Name = 'IEncryption';
            app.Panel = uipanel(app.IEncryption);
            app.UIAxes = uiaxes(app.Panel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '';
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = '';
            app.Label_7 = uilabel(app.Panel);
            app.Label_7.Text = '';
            app.Label_8 = uilabel(app.Panel);
            app.Label_8.Text = '';
            app.WelcometoIEncryptionLabel = uilabel(app.Panel);
            app.WelcometoIEncryptionLabel.Text = 'Welcome to IEncryption';
            app.Label_10 = uilabel(app.Panel);
            app.Label_10.Text = '';
            app.InputPanel = uipanel(app.Panel);
            app.InputPanel.Title = 'Input';
            app.DropDown = uidropdown(app.InputPanel);
            app.DropDown.Items = {'Encrypt', 'Decrypt'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Encrypt';
            app.BrowseButton = uibutton(app.InputPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.selectoptionLabel = uilabel(app.InputPanel);
            app.selectoptionLabel.Text = '1. select option';
            app.browsefilesLabel = uilabel(app.InputPanel);
            app.browsefilesLabel.Text = '2. browse file(s)';
            app.AndreMunoz2018Label = uilabel(app.IEncryption);
            app.AndreMunoz2018Label.Text = 'Andre Munoz @ 2018';
        end
    end

    methods (Access = public)

        % Construct app
        function app = I_Encryption

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IEncryption)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IEncryption)
        end
    end
end